classdef app_learning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Panel          matlab.ui.container.Panel
        Panel_2        matlab.ui.container.Panel
        Button_1       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Panel_3        matlab.ui.container.Panel
        Button_start   matlab.ui.control.Button
        Button_finish  matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    %将变量放入私有类中使其成为全局变量
    properties (Access = private)
        camera_flag     %摄像头打开标志
        img_in          %读入图像
        img_gray        %预处理后图像
        img_bw          %阈值分割后图像
        img_processed   %形态学处理后图像
        papa            
        pbpb
    end
    
%     properties (Access = private)    
%         %标记函数        
%         function [img_output]=Img_Tag (img_input,x,y,w,h)
%                 for i=1:375
%                     for j=1:605
%                         %标记中心点********************************************************************标记外接矩形水平边*************************标记外接矩形垂直边
%                         if ((i>((x+w/2)-5)) && (i<((x+w/2)+5))) && ((j>(y+h/2)-5)) && (j<((y+h/2)+5))||((i==x)||(i==(x+w))) && ((j>y)&&(j<(y+h)))||((j==y)||(j==(y+h))) && ((i>x)&&(i<(x+w)))
%                            a(i,j)=1;  
%                         else
%                              a(i,j)=0;
%                         end        
%                     end   
%                 end
%                     img_output=imadd(img_input,a);
%         end
%     end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_start
        function Button_startPushed(app, event)
          %开始按钮
          
          %开启摄像头
          row = videoinput('winvideo',1,'MJPG_640x480');            %调用摄像头，设置分辨率为640*480 row为摄像头拍摄图像 摄像头名称为winvideo 1
          triggerconfig(row,'manual');                              %设置摄像头触发模式为手动模式
          start(row);
          app.camera_flag=1;                                        %摄像头打开标志为真
          %进入图像处理循环
           while  app.camera_flag==1                  
                  a=getsnapshot(row);                               %得到摄像头的一帧


                %预处理
                  a=im2double(a);
                  b=rgb2gray(a);                                    %将a转换为灰度图像b
                %阈值分割  
                  T=graythresh(b);                                  %使用最大类间方差法求阈值T
                  c=imbinarize(b,T-0.17);                           %以T-0.17为阈值将b二值化为c
                %形态学处理 
                  pa=strel('disk',18);                              %Pa为半径为25的圆盘结构元素
                  d=imclose(c,pa);                                  %使用Pa结构元素对c执行闭运算
                  pb=strel('disk',36);                              %Pb为半径为50的圆盘结构元素
                  bw=imopen(d,pb);                                  %使用Pb结构元素对d执行开运算
                  bw=~bw;                                           %图像取反
                %求最大连通区域并保留         
                  imLabel = bwlabel(bw);                            %对各连通域进行标记
                  stats = regionprops(imLabel,'Area');              %求各连通域的大小
                  area = cat(1,stats.Area);                         %将括号里的三个量合在一起
                  index = find(area == max(area));                  %求最大连通域的索引
                  img = ismember(imLabel,index);                    %img为索引转化来的rgb图像
                %位置标记
                  box=regionprops(img,'BoundingBox');               %img为图像处理完成后的图像
                  x=box.BoundingBox(1);                             %最小外接矩形的起始点横坐标
                  y=box.BoundingBox(2);                             %最小外接矩形的起始点纵坐标
                  w=box.BoundingBox(3);                             %最小外接矩形的长
                  h=box.BoundingBox(4);                             %最小外接矩形的宽
                %取整
%                   x=fix(x);
%                   y=fix(y);
%                   w=fix(w);
%                   h=fix(h);
                %使用标记函数标记 
                 
                  imshow(b,'Parent',app.UIAxes);
                  hold(app.UIAxes,"on");
                  plot(app.UIAxes,x+w/2,y+h/2,'g+')%标记出中心点
                  rectangle(app.UIAxes,'position', [x,y,w,h],'EdgeColor', 'g');            %显示
                  pause(0.0001);%暂停一个极小的时间以显示外接矩形
            end   
          delete(row);%关闭摄像头


        end

        % Callback function
        function UIAxesButtonDown2(app, event)
            
        end

        % Button pushed function: Button_finish
        function Button_finishPushed(app, event)
            %结束按钮
            app.camera_flag=0;                                      %标志为假退出while循环，关闭摄像头
          
            
        end

        % Callback function
        function ImageClicked(app, event)
     
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
        %读入图像    
            app.img_in=imread('eye.png');                           %读入图像
            app.img_in=im2double(app.img_in);                       %转成双精度（imadd函数要求）
            imshow(app.img_in,'Parent',app.UIAxes); 
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
         %图像预处理 
            app.img_gray=rgb2gray(app.img_in);                      %将a转换为灰度图像b
          
      
            imshow(app.img_gray,'Parent',app.UIAxes);
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
        %阈值分割
            T=graythresh(app.img_gray);                             %使用最大类间方差法求阈值T
            app.img_bw=imbinarize(app.img_gray,T-0.17);             %以T-0.17为阈值将b二值化为c
            imshow(app.img_bw,'Parent',app.UIAxes);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
        %形态学处理 
            app.papa=18;
            app.pbpb=36;
            pa=strel('disk',app.papa);                              %Pa为半径为18的圆盘结构元素
            app.img_bw=imclose(app.img_bw,pa);                      %使用Pa结构元素对c执行闭运算
            pb=strel('disk',app.pbpb);                              %Pb为半径为36的圆盘结构元素
            app.img_processed=imopen(app.img_bw,pb);                %使用Pb结构元素对d执行开运算
            app.img_processed=~app.img_processed;                   %图像取反
            imshow(app.img_processed,'Parent',app.UIAxes);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
        %求最大连通区域并保留
            imLabel = bwlabel(app.img_processed);                   %对各连通域进行标记
            stats = regionprops(imLabel,'Area');                    %求各连通域的大小
            area = cat(1,stats.Area);                               %将括号里的三个量合在一起
            index = find(area == max(area));                        %求最大连通域的索引
            img = ismember(imLabel,index);                          %img为索引转化来的rgb图像
         %位置标记 
            box=regionprops(img,'BoundingBox');                     %img为图像处理完成后的图像
            x=box.BoundingBox(1);                                   %最小外接矩形的起始点横坐标
            y=box.BoundingBox(2);                                   %最小外接矩形的起始点纵坐标
            w=box.BoundingBox(3);                                   %最小外接矩形的长
            h=box.BoundingBox(4);                                   %最小外接矩形的宽
         %取整
            x=fix(x);
            y=fix(y);
            w=fix(w);
            h=fix(h);
            
            aa=Img_Tag (app.img_gray,y,x,w,h);                      %使用标记函数对中心坐标进行标记
            imshow(aa,'Parent',app.UIAxes);
%             imshow(app.img_gray,'Parent',app.UIAxes);
%             hold(app.UIAxes,"on");
%             plot(app.UIAxes,x+w/2,y+h/2,'g+')%标记出中心点
%             rectangle(app.UIAxes,'position', [x,y,w,h],'EdgeColor', 'g');
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '数字图像处理大作业';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Title = '人眼图片处理演示';
            app.Button_1 = uibutton(app.Panel_2, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '读入图像';
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '图像预处理';
            app.Button_3 = uibutton(app.Panel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '阈值分割';
            app.Button_4 = uibutton(app.Panel_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '形态学处理';
            app.Button_5 = uibutton(app.Panel_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '位置标记';
            app.Panel_3 = uipanel(app.Panel);
            app.Panel_3.Title = '实时演示';
            app.Button_start = uibutton(app.Panel_3, 'push');
            app.Button_start.ButtonPushedFcn = createCallbackFcn(app, @Button_startPushed, true);
            app.Button_start.Text = '开始';
            app.Button_finish = uibutton(app.Panel_3, 'push');
            app.Button_finish.ButtonPushedFcn = createCallbackFcn(app, @Button_finishPushed, true);
            app.Button_finish.Text = '结束';
            app.UIAxes = uiaxes(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_learning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end